classdef charge_capability < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        capacidaddecargaTab           matlab.ui.container.Tab
        CapacidaddecargaPanel         matlab.ui.container.Panel
        AsentamientosPanel            matlab.ui.container.Panel
        LmEditField_2                 matlab.ui.control.NumericEditField
        LmEditField_2Label            matlab.ui.control.Label
        qKpaEditField                 matlab.ui.control.NumericEditField
        qKpaEditFieldLabel            matlab.ui.control.Label
        HmEditField_2                 matlab.ui.control.NumericEditField
        HmEditField_2Label            matlab.ui.control.Label
        CalcularasentamientosButton   matlab.ui.control.Button
        CasosDeVariacion              matlab.ui.container.Panel
        SuckPaLabel_5                 matlab.ui.control.Label
        valorFijoVariacion            matlab.ui.control.Label
        ZapatacuadradaSwitch          matlab.ui.control.Switch
        ZapatacuadradaSwitchLabel     matlab.ui.control.Label
        IntervaloContinuoGrupoVariacion  matlab.ui.container.ButtonGroup
        secondContinuoVariacion       matlab.ui.control.RadioButton
        firstContinuoVariacion        matlab.ui.control.RadioButton
        IntervaloDiscontinuoGrupoVariacion  matlab.ui.container.ButtonGroup
        dnfDiscontinuoVariacion       matlab.ui.control.RadioButton
        bDiscontinuoVariacion         matlab.ui.control.RadioButton
        dfDiscontinuoVariacion        matlab.ui.control.RadioButton
        CalcularButton                matlab.ui.control.StateButton
        DatosdelacargaPanel           matlab.ui.container.Panel
        pOq                           matlab.ui.control.DropDown
        CalcularLabel                 matlab.ui.control.Label
        CasoCarga                     matlab.ui.control.DropDown
        CasoDropDownLabel             matlab.ui.control.Label
        NormativaGrupoCarga           matlab.ui.container.ButtonGroup
        CCP14                         matlab.ui.control.RadioButton
        NSR10                         matlab.ui.control.RadioButton
        inclinacionCarga              matlab.ui.control.NumericEditField
        SuckPaLabel_4                 matlab.ui.control.Label
        elCarga                       matlab.ui.control.NumericEditField
        SuckPaLabel_3                 matlab.ui.control.Label
        ebCarga                       matlab.ui.control.NumericEditField
        SuckPaLabel_2                 matlab.ui.control.Label
        DatosCimentacionPanel         matlab.ui.container.Panel
        FijoLabel                     matlab.ui.control.Label
        LongitudLabel                 matlab.ui.control.Label
        ContinuoLabel                 matlab.ui.control.Label
        DiscontinuouLabel             matlab.ui.control.Label
        fijoCimentacion               matlab.ui.control.NumericEditField
        longitudCimentacion           matlab.ui.control.NumericEditField
        continuoFinCimentacion        matlab.ui.control.NumericEditField
        continuoInicioCimentacion     matlab.ui.control.NumericEditField
        discontinuoAvanceCimentacion  matlab.ui.control.NumericEditField
        discontinuoFinCimentacion     matlab.ui.control.NumericEditField
        discontinuoInicioCimentacion  matlab.ui.control.NumericEditField
        bFinLabel                     matlab.ui.control.Label
        bInicioLabe                   matlab.ui.control.Label
        dfAvanceLabel                 matlab.ui.control.Label
        dfFinLabel                    matlab.ui.control.Label
        dfInicioLabe                  matlab.ui.control.Label
        DatosdelsueloPanel            matlab.ui.container.Panel
        phiSuelo                      matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        inclinacionSuelo              matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        cohesionSuelo                 matlab.ui.control.NumericEditField
        SuckPaLabel                   matlab.ui.control.Label
        puSaturadoSuelo               matlab.ui.control.NumericEditField
        YsatkNmLabel                  matlab.ui.control.Label
        puNaturalSuelo                matlab.ui.control.NumericEditField
        YnatkNmEditFieldLabel         matlab.ui.control.Label
        tipoSuelo                     matlab.ui.control.EditField
        TipodesueloEditFieldLabel     matlab.ui.control.Label
        image                         matlab.ui.control.Image
        CimentacionjuntoataludTab     matlab.ui.container.Tab
        pasoLabel                     matlab.ui.control.Label
        finLabel_2                    matlab.ui.control.Label
        inicioLabel_2                 matlab.ui.control.Label
        finLabel                      matlab.ui.control.Label
        inicioLabel                   matlab.ui.control.Label
        EditField3                    matlab.ui.control.NumericEditField
        EditField2                    matlab.ui.control.NumericEditField
        EditField_4                   matlab.ui.control.NumericEditField
        LmEditField                   matlab.ui.control.NumericEditField
        LmEditFieldLabel              matlab.ui.control.Label
        BmEditField                   matlab.ui.control.NumericEditField
        BmEditFieldLabel              matlab.ui.control.Label
        DfLabel                       matlab.ui.control.Label
        variablefijaLabel             matlab.ui.control.Label
        intervalodiscretoButtonGroup  matlab.ui.container.ButtonGroup
        DfButton_2                    matlab.ui.control.RadioButton
        bButton_2                     matlab.ui.control.RadioButton
        intervalocontinuoButtonGroup  matlab.ui.container.ButtonGroup
        DfButton                      matlab.ui.control.RadioButton
        bButton                       matlab.ui.control.RadioButton
        BButton                       matlab.ui.control.RadioButton
        DfEditField                   matlab.ui.control.NumericEditField
        DfEditFieldLabel              matlab.ui.control.Label
        cEditField                    matlab.ui.control.NumericEditField
        cEditFieldLabel               matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        EditField_3Label              matlab.ui.control.Label
        bmEditField                   matlab.ui.control.NumericEditField
        bmEditFieldLabel              matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        EditField_2Label              matlab.ui.control.Label
        HmEditField                   matlab.ui.control.NumericEditField
        HmEditFieldLabel              matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    methods (Access = public)
        function charge = bearingCapacity(~, gammaM, gammaSat, cohe, phi, betta, Df, B, L, Dnf, exB, exL, thetta, K, getQ)

            phiRad = deg2rad(phi);
            % Bearing capability factors
            Nq = tan(deg2rad(45+phi/2))^2*exp(1)^(pi*tan(phiRad));
            if phiRad == 0
                Nc = 5.14;
            else 
                Nc = (Nq-1)/tan(phiRad);
            end
            Ny = 2*(Nq+1)*tan(phiRad);
    
            % Efective dimensions
            Bp = B-(2*exB);
            Lp = L-(2*exL);
            Bp_Lp = Bp/Lp;

            % Shape factors
            fc = 1+(Bp_Lp*(Nq/Nc));
            fq = 1+(Bp_Lp*(tan(phiRad)));
            fy = 1-(0.4*Bp_Lp);
    
            % Depth factors
            if Df/B <= 1
                DfB=Df/B;
            else
                DfB=atan(Df/B);
            end
            dc = 1+(0.4*DfB);
            dq = 1+2*tan(phiRad)*(1-sin(phiRad))^2*DfB;
            dy = 1;
    
            % Charge inclination factors
            ic = (1-(thetta/90))^2;
            iq = ic;
            if thetta >= phi
                iy = 0;
            else
                iy = (1-(thetta/phi))^2;
            end
            
            % Land inclination factors
            gc = 1 - betta/145;
            gq = (1-0.5*tan(deg2rad(betta)))^5;
            gy = gq;

            % Efective pressure
            if Dnf <= 0
                q = (-Dnf*10)+(gammaSat*Df)-(10*(Df-Dnf));
            elseif Dnf<=Df && Dnf>0
                q = Dnf*gammaM+(Df-Dnf)*gammaSat-((Df-Dnf)*10);
            else 
                q = Df*gammaM;
            end
    
            % Weighted specific weight
            DfDcr = Df+B;
            if Dnf <= 0
                Yer = gammaSat-10;
            elseif Dnf<=Df && Dnf>=0
                Yer = gammaSat-10;
            elseif Dnf>=Df && Dnf<=DfDcr
                Yer = (gammaSat-10)+((Dnf-Df)/B)*(gammaM-(gammaSat-10));
            else
                Yer = gammaM;
            end
    
            % Charge capability
            first = cohe*Nc*fc*dc*ic*gc;
            second = q*Nq*fq*dq*iq*gq;
            third = 0.5*Yer*B*Ny*fy*dy*iy*gy;
            qu = first+second+third;

            % Permissible load
            quadm = qu*K;
            if getQ
                charge = quadm;
            else
                charge = quadm*Bp*Lp;
            end
        end

        function content = getPropertie(~, texto)

            start = strfind(texto, '(');
            ending = strfind(texto, ')');
            content = texto(start+1:ending-1);
        end

        function graphic(~, legendInfo, normaTitle, casoCarga, xLabel, getQ) 
            legend(legendInfo);

            xlabel(xLabel)
            if getQ
                ylabel('qu_{adm} (kPa)')
                title(strcat('qu_{adm} vs B - ',normaTitle,'(',casoCarga,')'))
            else
                ylabel('P_{adm} (kN)')
                title(strcat('Pu_{adm} vs B :',normaTitle,'(',casoCarga,')'))
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NormativaGrupoCargaSelectionChanged(app, event)

        end

        % Selection changed function: NormativaGrupoCarga
        function NormativaGrupoCargaSelectionChanged2(app, event)

            if app.NSR10.Value == 1
                app.CasoCarga.Items = {'D + L', 'D + L + E'};
            elseif app.CCP14.Value == 1
                app.CasoCarga.Items = {'Resistencia I', 'Evento extremo I'};
            end
            
            app.CasoCarga.Value = app.CasoCarga.Items{1};   
        end

        % Value changed function: CalcularButton
        function CalcularButtonValueChanged(app, event)
            % Soil properties
            yNat = app.puNaturalSuelo.Value;
            ySat = app.puSaturadoSuelo.Value;
            cohe = app.cohesionSuelo.Value;
            phi = app.phiSuelo.Value;
            betta = app.inclinacionSuelo.Value;

            % Foundation properties
            discontinuousSpace = app.discontinuoInicioCimentacion.Value : app.discontinuoAvanceCimentacion.Value : app.discontinuoFinCimentacion.Value;
            continuousSpace = linspace(app.continuoInicioCimentacion.Value, app.continuoFinCimentacion.Value);
            L = app.longitudCimentacion.Value;
            fixedValue = app.fijoCimentacion.Value;

            % Load properties
            eB = app.ebCarga.Value;
            eL = app.elCarga.Value;
            thetta = app.inclinacionCarga.Value;
            if app.NSR10.Value == 1
                normaTitle = ' NSR-10 ';
                switch app.CasoCarga.Value
                    case 'D + L'
                        K = 1/3;
                    case 'D + L + E'
                        K = 2/3;
                end
            elseif app.CCP14.Value == 1
                normaTitle = ' CCP-14 ';
                switch app.CasoCarga.Value
                    case 'Resistencia I'
                        K = 0.45;
                    case 'Evento extremo I'
                        K = 1;
                end
            else
                normaTitle = '';
                K = 1;
            end

            getQ = app.pOq.Value == 'q';
            
            % Graph
            for i = 1 : length(discontinuousSpace)
                if app.ZapatacuadradaSwitch.Value == "No"
                    switch(app.DiscontinuouLabel.Text)
                        case 'Df (m)'
                            switch(app.ContinuoLabel.Text)
                                case 'B (m)'
                                    Df = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(b) bearingCapacity(app, yNat, ySat, cohe, phi, betta, Df, b, L, fixedValue, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['Df ' num2str(Df) 'm'];
                                case 'Dnf (m)'
                                    Df = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(Dnf) bearingCapacity(app, yNat, ySat, cohe, phi, betta, Df, fixedValue, L, Dnf, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['Df ' num2str(Df) 'm'];
                            end
                        case 'B (m)'
                            switch(app.ContinuoLabel.Text)
                                case 'Df (m)'
                                    B = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(Df) bearingCapacity(app, yNat, ySat, cohe, phi, betta, Df, B, L, fixedValue, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['B ' num2str(B) 'm'];
                                case 'Dnf (m)'
                                    B = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(Dnf) bearingCapacity(app, yNat, ySat, cohe, phi, betta, fixedValue, B, L, Dnf, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['B ' num2str(B) 'm'];
                            end
                        case 'Dnf (m)'
                            switch(app.ContinuoLabel.Text)
                                case 'Df (m)'
                                    Dnf = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(Df) bearingCapacity(app, yNat, ySat, cohe, phi, betta, Df, fixedValue, L, Dnf, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['Dnf ' num2str(Dnf) 'm'];
                                case 'B (m)'
                                    Dnf = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(B) bearingCapacity(app, yNat, ySat, cohe, phi, betta, fixedValue, B, L, Dnf, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['Dnf ' num2str(Dnf) 'm'];
                            end
                    end
                else
                    switch(app.DiscontinuouLabel.Text)
                        case 'Df (m)'
                            switch(app.ContinuoLabel.Text)
                                case 'B (m)'
                                    Df = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(b) bearingCapacity(app, yNat, ySat, cohe, phi, betta, Df, b, b, fixedValue, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['Df ' num2str(Df) 'm'];
                                case 'Dnf (m)'
                                    Df = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(Dnf) bearingCapacity(app, yNat, ySat, cohe, phi, betta, Df, fixedValue, fixedValue, Dnf, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['Df ' num2str(Df) 'm'];
                               end
                        case 'B (m)'
                            switch(app.ContinuoLabel.Text)
                                case 'Df (m)'
                                    B = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(Df) bearingCapacity(app, yNat, ySat, cohe, phi, betta, Df, B, B, fixedValue, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['B ' num2str(B) 'm'];
                                case 'Dnf (m)'
                                    B = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(Dnf) bearingCapacity(app, yNat, ySat, cohe, phi, betta, fixedValue, B, B, Dnf, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['B ' num2str(B) 'm'];
                            end
                        case 'Dnf (m)'
                            switch(app.ContinuoLabel.Text)
                                case 'Df (m)'
                                    Dnf = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(Df) bearingCapacity(app, yNat, ySat, cohe, phi, betta, Df, fixedValue, fixedValue, Dnf, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['Dnf ' num2str(Dnf) 'm'];
                                case 'B (m)'
                                    Dnf = discontinuousSpace(i);
                                    resultSpace = arrayfun(@(B) bearingCapacity(app, yNat, ySat, cohe, phi, betta, fixedValue, B, B, Dnf, eB, eL, thetta, K, getQ),continuousSpace);
                                    legendInfo{i} = ['Dnf ' num2str(Dnf) 'm'];
                            end
                    end
                end
                plot(continuousSpace,resultSpace);
                hold on
            end


            graphic(app, legendInfo, normaTitle, app.CasoCarga.Value, app.ContinuoLabel.Text, getQ)
           

        end

        % Selection changed function: IntervaloDiscontinuoGrupoVariacion
        function IntervaloDiscontinuoGrupoVariacionSelectionChanged(app, event)
            if app.dfDiscontinuoVariacion.Value == 1
                app.firstContinuoVariacion.Text = 'Base (B)';
                app.secondContinuoVariacion_2.Text = 'Nivel freático (Dnf)';
                app.DiscontinuouLabel.Text = 'Df (m)';
            elseif app.bDiscontinuoVariacion.Value == 1
                app.firstContinuoVariacion.Text = 'Desplante (Df)';
                app.secondContinuoVariacion_2.Text = 'Nivel freático (Dnf)';
                app.DiscontinuouLabel.Text = 'B (m)';
            elseif app.dnfDiscontinuoVariacion.Value == 1
                app.firstContinuoVariacion.Text = 'Desplante (Df)';
                app.secondContinuoVariacion_2.Text = 'Base (B)';
                app.DiscontinuouLabel.Text = 'Dnf (m)';
            end
            radioButtons = app.IntervaloContinuoGrupoVariacion.Children;
            app.IntervaloContinuoGrupoVariacion.SelectedObject = radioButtons(2);
            app.valorFijoVariacion.Text = app.secondContinuoVariacion_2.Text;
            app.FijoLabel.Text = strcat(getPropertie(app,app.secondContinuoVariacion_2.Text), ' (m)');
            app.ContinuoLabel.Text = strcat(getPropertie(app,app.firstContinuoVariacion.Text), ' (m)');

        end

        % Selection changed function: IntervaloContinuoGrupoVariacion
        function IntervaloContinuoGrupoVariacionSelectionChanged(app, event)
            if app.firstContinuoVariacion.Value == 1
                app.valorFijoVariacion.Text = app.secondContinuoVariacion_2.Text;
                app.FijoLabel.Text = strcat(getPropertie(app,app.secondContinuoVariacion_2.Text), ' (m)');
                app.ContinuoLabel.Text = strcat(getPropertie(app,app.firstContinuoVariacion.Text), ' (m)');
            elseif app.secondContinuoVariacion_2.Value == 1
                app.valorFijoVariacion.Text = app.firstContinuoVariacion.Text;
                app.FijoLabel.Text = strcat(getPropertie(app,app.firstContinuoVariacion.Text), ' (m)');
                app.ContinuoLabel.Text = strcat(getPropertie(app,app.secondContinuoVariacion_2.Text), ' (m)');
            end
            
        end

        % Value changed function: ZapatacuadradaSwitch
        function ZapatacuadradaSwitchValueChanged(app, event)
            if app.ZapatacuadradaSwitch.Value == "Sí"
                app.longitudCimentacion.Value = 0;
                app.longitudCimentacion.Editable = 'off';
            elseif app.ZapatacuadradaSwitch.Value == "No"
                app.longitudCimentacion.Value = 4;
                app.longitudCimentacion.Editable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.capacidaddecargaTab = uitab(app.TabGroup);
            app.capacidaddecargaTab.Title = 'capacidad de carga';
            app.image = uiimage(app.capacidaddecargaTab);
            app.image.ImageSource = 'Captura.PNG';
            app.CapacidaddecargaPanel = uipanel(app.capacidaddecargaTab);
            app.CapacidaddecargaPanel.Title = 'Capacidad de carga';
            app.DatosdelsueloPanel = uipanel(app.CapacidaddecargaPanel);
            app.DatosdelsueloPanel.Title = 'Datos del suelo';
            app.TipodesueloEditFieldLabel = uilabel(app.DatosdelsueloPanel);
            app.TipodesueloEditFieldLabel.Text = 'Tipo de suelo';
            app.tipoSuelo = uieditfield(app.DatosdelsueloPanel, 'text');
            app.YnatkNmEditFieldLabel = uilabel(app.DatosdelsueloPanel);
            app.YnatkNmEditFieldLabel.Text = 'Ynat (kN/m³)';
            app.puNaturalSuelo = uieditfield(app.DatosdelsueloPanel, 'numeric');
            app.puNaturalSuelo.Limits = [0 Inf];
            app.puNaturalSuelo.Value = 16;
            app.YsatkNmLabel = uilabel(app.DatosdelsueloPanel);
            app.YsatkNmLabel.Text = 'Ysat (kN/m³)';
            app.puSaturadoSuelo = uieditfield(app.DatosdelsueloPanel, 'numeric');
            app.puSaturadoSuelo.Limits = [0 Inf];
            app.puSaturadoSuelo.Value = 21;
            app.SuckPaLabel = uilabel(app.DatosdelsueloPanel);
            app.SuckPaLabel.Text = 'Su, c (kPa)';
            app.cohesionSuelo = uieditfield(app.DatosdelsueloPanel, 'numeric');
            app.cohesionSuelo.Limits = [0 Inf];
            app.Label = uilabel(app.DatosdelsueloPanel);
            app.Label.Text = 'β (°)';
            app.inclinacionSuelo = uieditfield(app.DatosdelsueloPanel, 'numeric');
            app.inclinacionSuelo.Limits = [0 90];
            app.Label_2 = uilabel(app.DatosdelsueloPanel);
            app.Label_2.Text = 'φ (°)';
            app.phiSuelo = uieditfield(app.DatosdelsueloPanel, 'numeric');
            app.phiSuelo.Limits = [0 55];
            app.phiSuelo.Value = 24;
            app.DatosCimentacionPanel = uipanel(app.CapacidaddecargaPanel);
            app.DatosCimentacionPanel.Title = 'Datos de la Cimentación';
            app.dfInicioLabe = uilabel(app.DatosCimentacionPanel);
            app.dfInicioLabe.Text = 'Inicio';
            app.dfFinLabel = uilabel(app.DatosCimentacionPanel);
            app.dfFinLabel.Text = 'Fin';
            app.dfAvanceLabel = uilabel(app.DatosCimentacionPanel);
            app.dfAvanceLabel.Text = 'Avance';
            app.bInicioLabe = uilabel(app.DatosCimentacionPanel);
            app.bInicioLabe.Text = 'Inicio';
            app.bFinLabel = uilabel(app.DatosCimentacionPanel);
            app.bFinLabel.Text = 'Fin';
            app.discontinuoInicioCimentacion = uieditfield(app.DatosCimentacionPanel, 'numeric');
            app.discontinuoInicioCimentacion.Limits = [0 Inf];
            app.discontinuoInicioCimentacion.Value = 0.5;
            app.discontinuoFinCimentacion = uieditfield(app.DatosCimentacionPanel, 'numeric');
            app.discontinuoFinCimentacion.Limits = [0 Inf];
            app.discontinuoFinCimentacion.Value = 2;
            app.discontinuoAvanceCimentacion = uieditfield(app.DatosCimentacionPanel, 'numeric');
            app.discontinuoAvanceCimentacion.Limits = [0.1 Inf];
            app.discontinuoAvanceCimentacion.Value = 0.5;
            app.continuoInicioCimentacion = uieditfield(app.DatosCimentacionPanel, 'numeric');
            app.continuoInicioCimentacion.Limits = [0.1 Inf];
            app.continuoInicioCimentacion.Value = 0.5;
            app.continuoFinCimentacion = uieditfield(app.DatosCimentacionPanel, 'numeric');
            app.continuoFinCimentacion.Limits = [0.1 Inf];
            app.continuoFinCimentacion.Value = 2;
            app.longitudCimentacion = uieditfield(app.DatosCimentacionPanel, 'numeric');
            app.longitudCimentacion.Limits = [0 Inf];
            app.longitudCimentacion.Value = 4;
            app.fijoCimentacion = uieditfield(app.DatosCimentacionPanel, 'numeric');
            app.fijoCimentacion.Value = 2.5;
            app.DiscontinuouLabel = uilabel(app.DatosCimentacionPanel);
            app.DiscontinuouLabel.Text = 'Df (m)';
            app.ContinuoLabel = uilabel(app.DatosCimentacionPanel);
            app.ContinuoLabel.Text = 'B (m)';
            app.LongitudLabel = uilabel(app.DatosCimentacionPanel);
            app.LongitudLabel.Text = 'L (m)';
            app.FijoLabel = uilabel(app.DatosCimentacionPanel);
            app.FijoLabel.Text = 'Dnf (m)';
            app.DatosdelacargaPanel = uipanel(app.CapacidaddecargaPanel);
            app.DatosdelacargaPanel.Title = 'Datos de la carga';
            app.SuckPaLabel_2 = uilabel(app.DatosdelacargaPanel);
            app.SuckPaLabel_2.Text = 'eB (m)';
            app.ebCarga = uieditfield(app.DatosdelacargaPanel, 'numeric');
            app.SuckPaLabel_3 = uilabel(app.DatosdelacargaPanel);
            app.SuckPaLabel_3.Text = 'eL(m)';
            app.elCarga = uieditfield(app.DatosdelacargaPanel, 'numeric');
            app.SuckPaLabel_4 = uilabel(app.DatosdelacargaPanel);
            app.SuckPaLabel_4.Text = 'θ (°)';
            app.inclinacionCarga = uieditfield(app.DatosdelacargaPanel, 'numeric');
            app.inclinacionCarga.Limits = [0 Inf];
            app.NormativaGrupoCarga = uibuttongroup(app.DatosdelacargaPanel);
            app.NormativaGrupoCarga.SelectionChangedFcn = createCallbackFcn(app, @NormativaGrupoCargaSelectionChanged2, true);
            app.NormativaGrupoCarga.Title = 'Normativa';
            app.NSR10 = uiradiobutton(app.NormativaGrupoCarga);
            app.NSR10.Text = 'NSR-10';
            app.NSR10.Value = true;
            app.CCP14 = uiradiobutton(app.NormativaGrupoCarga);
            app.CCP14.Text = 'CCP-14';
            app.CasoDropDownLabel = uilabel(app.DatosdelacargaPanel);
            app.CasoDropDownLabel.Text = 'Caso';
            app.CasoCarga = uidropdown(app.DatosdelacargaPanel);
            app.CasoCarga.Items = {'D + L', 'D + L + E'};
            app.CasoCarga.Value = 'D + L';
            app.CalcularLabel = uilabel(app.DatosdelacargaPanel);
            app.CalcularLabel.Text = 'Calcular';
            app.pOq = uidropdown(app.DatosdelacargaPanel);
            app.pOq.Items = {'P', 'q'};
            app.pOq.Value = 'P';
            app.CalcularButton = uibutton(app.CapacidaddecargaPanel, 'state');
            app.CalcularButton.ValueChangedFcn = createCallbackFcn(app, @CalcularButtonValueChanged, true);
            app.CalcularButton.Text = 'Calcular';
            app.CasosDeVariacion = uipanel(app.CapacidaddecargaPanel);
            app.CasosDeVariacion.Title = 'Casos de variación';
            app.IntervaloDiscontinuoGrupoVariacion = uibuttongroup(app.CasosDeVariacion);
            app.IntervaloDiscontinuoGrupoVariacion.SelectionChangedFcn = createCallbackFcn(app, @IntervaloDiscontinuoGrupoVariacionSelectionChanged, true);
            app.IntervaloDiscontinuoGrupoVariacion.Title = 'Intervalo discontinuo';
            app.dfDiscontinuoVariacion = uiradiobutton(app.IntervaloDiscontinuoGrupoVariacion);
            app.dfDiscontinuoVariacion.Text = 'Desplante (Df)';
            app.dfDiscontinuoVariacion.Value = true;
            app.bDiscontinuoVariacion = uiradiobutton(app.IntervaloDiscontinuoGrupoVariacion);
            app.bDiscontinuoVariacion.Text = 'Base (B)';
            app.dnfDiscontinuoVariacion = uiradiobutton(app.IntervaloDiscontinuoGrupoVariacion);
            app.dnfDiscontinuoVariacion.Text = 'Nivel freático (Dnf)';
            app.IntervaloContinuoGrupoVariacion = uibuttongroup(app.CasosDeVariacion);
            app.IntervaloContinuoGrupoVariacion.SelectionChangedFcn = createCallbackFcn(app, @IntervaloContinuoGrupoVariacionSelectionChanged, true);
            app.IntervaloContinuoGrupoVariacion.Title = 'Intervalo continuo';
            app.firstContinuoVariacion = uiradiobutton(app.IntervaloContinuoGrupoVariacion);
            app.firstContinuoVariacion.Text = 'Base (B)';
            app.firstContinuoVariacion.Value = true;
            app.secondContinuoVariacion = uiradiobutton(app.IntervaloContinuoGrupoVariacion);
            app.secondContinuoVariacion.Text = 'Nivel freático (Dnf)';
            app.ZapatacuadradaSwitchLabel = uilabel(app.CasosDeVariacion);
            app.ZapatacuadradaSwitchLabel.Text = 'Zapata cuadrada';
            app.ZapatacuadradaSwitch = uiswitch(app.CasosDeVariacion, 'slider');
            app.ZapatacuadradaSwitch.Items = {'No', 'Sí'};
            app.ZapatacuadradaSwitch.ValueChangedFcn = createCallbackFcn(app, @ZapatacuadradaSwitchValueChanged, true);
            app.ZapatacuadradaSwitch.Value = 'No';
            app.valorFijoVariacion = uilabel(app.CasosDeVariacion);
            app.valorFijoVariacion.Text = 'Nivel freático (Dnf)';
            app.SuckPaLabel_5 = uilabel(app.CasosDeVariacion);
            app.SuckPaLabel_5.Text = 'Valor fijo';
            app.CalcularasentamientosButton = uibutton(app.CapacidaddecargaPanel, 'push');
            app.CalcularasentamientosButton.Text = 'Calcular asentamientos';
            app.AsentamientosPanel = uipanel(app.CapacidaddecargaPanel);
            app.AsentamientosPanel.Title = 'Asentamientos';
            app.HmEditField_2Label = uilabel(app.AsentamientosPanel);
            app.HmEditField_2Label.Text = 'H (m)';
            app.HmEditField_2 = uieditfield(app.AsentamientosPanel, 'numeric');
            app.qKpaEditFieldLabel = uilabel(app.AsentamientosPanel);
            app.qKpaEditFieldLabel.Text = 'q (Kpa)';
            app.qKpaEditField = uieditfield(app.AsentamientosPanel, 'numeric');
            app.LmEditField_2Label = uilabel(app.AsentamientosPanel);
            app.LmEditField_2Label.Text = 'L (m)';
            app.LmEditField_2 = uieditfield(app.AsentamientosPanel, 'numeric');
            app.CimentacionjuntoataludTab = uitab(app.TabGroup);
            app.CimentacionjuntoataludTab.Title = 'Cimentacion junto a talud';
            app.Image = uiimage(app.CimentacionjuntoataludTab);
            app.Image.ImageSource = 'C:\Users\WBEIMAR FERNANDEZ\Desktop\cimentaciones profundas\CAPACIDAD DE CARGA\Captura de pantalla 2026-03-17 013138.png';
            app.HmEditFieldLabel = uilabel(app.CimentacionjuntoataludTab);
            app.HmEditFieldLabel.Text = 'H (m)';
            app.HmEditField = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.EditField_2Label = uilabel(app.CimentacionjuntoataludTab);
            app.EditField_2Label.Text = 'β';
            app.EditField_2 = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.bmEditFieldLabel = uilabel(app.CimentacionjuntoataludTab);
            app.bmEditFieldLabel.Text = 'b (m)';
            app.bmEditField = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.EditField_3Label = uilabel(app.CimentacionjuntoataludTab);
            app.EditField_3Label.Text = 'γ';
            app.EditField_3 = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.cEditFieldLabel = uilabel(app.CimentacionjuntoataludTab);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.DfEditFieldLabel = uilabel(app.CimentacionjuntoataludTab);
            app.DfEditFieldLabel.Text = 'Df';
            app.DfEditField = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.intervalocontinuoButtonGroup = uibuttongroup(app.CimentacionjuntoataludTab);
            app.intervalocontinuoButtonGroup.Title = 'intervalo continuo';
            app.BButton = uiradiobutton(app.intervalocontinuoButtonGroup);
            app.BButton.Text = 'B';
            app.BButton.Value = true;
            app.bButton = uiradiobutton(app.intervalocontinuoButtonGroup);
            app.bButton.Text = 'b';
            app.DfButton = uiradiobutton(app.intervalocontinuoButtonGroup);
            app.DfButton.Text = 'Df';
            app.intervalodiscretoButtonGroup = uibuttongroup(app.CimentacionjuntoataludTab);
            app.intervalodiscretoButtonGroup.Title = 'intervalo discreto';
            app.bButton_2 = uiradiobutton(app.intervalodiscretoButtonGroup);
            app.bButton_2.Text = 'b';
            app.bButton_2.Value = true;
            app.DfButton_2 = uiradiobutton(app.intervalodiscretoButtonGroup);
            app.DfButton_2.Text = 'Df';
            app.variablefijaLabel = uilabel(app.CimentacionjuntoataludTab);
            app.variablefijaLabel.Text = 'variable fija';
            app.DfLabel = uilabel(app.CimentacionjuntoataludTab);
            app.DfLabel.Text = 'Df';
            app.BmEditFieldLabel = uilabel(app.CimentacionjuntoataludTab);
            app.BmEditFieldLabel.Text = 'B (m)';
            app.BmEditField = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.LmEditFieldLabel = uilabel(app.CimentacionjuntoataludTab);
            app.LmEditFieldLabel.Text = 'L (m)';
            app.LmEditField = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.EditField_4 = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.EditField2 = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.EditField3 = uieditfield(app.CimentacionjuntoataludTab, 'numeric');
            app.inicioLabel = uilabel(app.CimentacionjuntoataludTab);
            app.inicioLabel.Text = 'inicio';
            app.finLabel = uilabel(app.CimentacionjuntoataludTab);
            app.finLabel.Text = 'fin';
            app.inicioLabel_2 = uilabel(app.CimentacionjuntoataludTab);
            app.inicioLabel_2.Text = 'inicio';
            app.finLabel_2 = uilabel(app.CimentacionjuntoataludTab);
            app.finLabel_2.Text = 'fin';
            app.pasoLabel = uilabel(app.CimentacionjuntoataludTab);
            app.pasoLabel.Text = 'paso';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = charge_capability

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end